classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SimilarcoauthorsPanel       matlab.ui.container.Panel
        FindSimilarButton           matlab.ui.control.Button
        ChooseAuthorEditField       matlab.ui.control.EditField
        ChooseAuthorEditFieldLabel  matlab.ui.control.Label
        Top10ListBox                matlab.ui.control.ListBox
        Top10ListBoxLabel           matlab.ui.control.Label
        MostImportantAuthorsPanel   matlab.ui.container.Panel
        ListAuthorsButton           matlab.ui.control.Button
        Top20ListBox                matlab.ui.control.ListBox
        Top20ListBoxLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
        end

        % Button pushed function: ListAuthorsButton
        function ListAuthorsButtonPushed(app, event)
            struct = load("dblp.mat"); %must be on the same folder
            dg=digraph(struct.Problem.A); % use adjaceny matrix for directional graph
            c=0.6; %mentioned in the exercise
            n=numnodes(dg);
            A=full(adjacency(dg));
            o=outdegree(dg);
            z=o==0;
            o(z)=1;
            W=A./o';% normalized A
            p=ones(n,1)*(1/n);
            for k=1:100
                p=c*W*p+(1-c)/n;
            end
            [~,index]=maxk(p,20);
            authors = struct.Problem.authors;
            top_20_authors = authors(index);
            app.Top20ListBox.Items = top_20_authors;
        end

        % Button pushed function: FindSimilarButton
        function FindSimilarButtonPushed(app, event)
            author_of_interest = app.ChooseAuthorEditField.Value;
            struct = load("dblp.mat"); %must be on the same folder
            authors = struct.Problem.authors;
            if not(ismember(authors, author_of_interest))
                f = msgbox("Author not found!");
            else
                index_of_author = authors==author_of_interest;
                struct = load("dblp.mat"); %must be on the same folder
                dg=digraph(struct.Problem.A);
    
                c=0.6;
                A=adjacency(dg);
                o=outdegree(dg);
                z=o==0;
                o(z)=1;
                A=A./o';
                n=numnodes(dg);
                I=eye(n);
                sr=I;
                for k=1:100
                  sr=max(c*A'*sr*A,I);
                end
                
                sims=sr(:,index_of_author);
                [~,index] = maxk(sims,11); % author of interest and most similar author have the same name
                
                top_10_similarites = authors(index);
                top_10_similarites(1) = []; % remove first record as this is the name of the author
                app.Top10ListBox.Items = top_10_similarites;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MostImportantAuthorsPanel = uipanel(app.UIFigure);
            app.MostImportantAuthorsPanel.Title = 'Most Important Authors';
            app.Top20ListBoxLabel = uilabel(app.MostImportantAuthorsPanel);
            app.Top20ListBoxLabel.Text = 'Top 20';
            app.Top20ListBox = uilistbox(app.MostImportantAuthorsPanel);
            app.Top20ListBox.Items = {''};
            app.Top20ListBox.Value = '';
            app.ListAuthorsButton = uibutton(app.MostImportantAuthorsPanel, 'push');
            app.ListAuthorsButton.ButtonPushedFcn = createCallbackFcn(app, @ListAuthorsButtonPushed, true);
            app.ListAuthorsButton.Text = 'List Authors';
            app.SimilarcoauthorsPanel = uipanel(app.UIFigure);
            app.SimilarcoauthorsPanel.Title = 'Similar co-authors';
            app.Top10ListBoxLabel = uilabel(app.SimilarcoauthorsPanel);
            app.Top10ListBoxLabel.Text = 'Top 10';
            app.Top10ListBox = uilistbox(app.SimilarcoauthorsPanel);
            app.Top10ListBox.Items = {};
            app.Top10ListBox.Value = {};
            app.ChooseAuthorEditFieldLabel = uilabel(app.SimilarcoauthorsPanel);
            app.ChooseAuthorEditFieldLabel.Text = 'Choose Author';
            app.ChooseAuthorEditField = uieditfield(app.SimilarcoauthorsPanel, 'text');
            app.FindSimilarButton = uibutton(app.SimilarcoauthorsPanel, 'push');
            app.FindSimilarButton.ButtonPushedFcn = createCallbackFcn(app, @FindSimilarButtonPushed, true);
            app.FindSimilarButton.Text = 'Find Similar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end